classdef GUI_Entrenamiento < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        ValoresparaentrenamientoLabel  matlab.ui.control.Label
        NeuronasSpinner                matlab.ui.control.Spinner
        NeuronasLabel                  matlab.ui.control.Label
        pocasSpinner                   matlab.ui.control.Spinner
        pocasLabel                     matlab.ui.control.Label
        AlphaSpinner                   matlab.ui.control.Spinner
        AlphaLabel                     matlab.ui.control.Label
        RealizarEntrenamientoButton    matlab.ui.control.Button
        CenterPanel                    matlab.ui.container.Panel
        eTextArea                      matlab.ui.control.TextArea
        eTextAreaLabel                 matlab.ui.control.Label
        TerminasteelentrenamientoRealizaunapruebaLabel  matlab.ui.control.Label
        PruebadeEntrenamientoButton    matlab.ui.control.Button
        EntrenamientoRedNeuronalMulticapaLabel  matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        ATextArea                      matlab.ui.control.TextArea
        ATextAreaLabel                 matlab.ui.control.Label
        TTextArea                      matlab.ui.control.TextArea
        TTextAreaLabel                 matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        neuronas = 10; % Cantidad de neuronas de la capa oculta
        epocas = 1000; % Cantidad de épocas para el entrenamiento
        alpha = 0.01;  % Índice de aprendizaje
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NeuronasSpinner
        function NeuronasSpinnerValueChanged(app, event)
            app.neuronas = app.NeuronasSpinner.Value;
        end

        % Value changed function: pocasSpinner
        function pocasSpinnerValueChanged(app, event)
            app.epocas = app.pocasSpinner.Value;
        end

        % Value changed function: AlphaSpinner
        function AlphaSpinnerValueChanged(app, event)
            app.alpha = app.AlphaSpinner.Value;
        end

        % Button pushed function: RealizarEntrenamientoButton
        function RealizarEntrenamientoButtonPushed(app, event)
            % Se limpian los textArea para la impresión
            app.ATextArea.Value = '';
            app.TTextArea.Value = '';
            app.eTextArea.Value = '';

            % Llamado de función que realiza el entrenamiento
            [a, T, e] = AlgoritmoGrupo3(app.neuronas, app.epocas, app.alpha);
            

            % Tratamiento de matrices para mejor visualización
            corchetes = ["[","]"]; % Los corchetes que se borrarán de las matrices

            stringA = replace(mat2str(a), ' 1', '    1');               % Se agrega espaciado entre números
            stringA = replace(stringA, ' -1', '   -1');                 % El espaciado que se agrega antes de -1 es menor (por el -)
            stringA = erase(replace(stringA, ';', newline),corchetes);  % El ; indica un cambio de línea
            
            stringT = replace(mat2str(T), ' 1', '    1');
            stringT = replace(stringT, ' -1', '   -1');
            stringT = erase(replace(stringT, ';', newline),corchetes);

            stringE = replace(mat2str(e), ' 0', '    0');
            stringE = replace(stringE, ' -', '   -');
            stringE = erase(replace(stringE, ';', newline),corchetes);

            % Muestreo en pantalla de los resultados
            app.ATextArea.Value = stringA;
            app.TTextArea.Value = stringT;
            app.eTextArea.Value = stringE;
        end

        % Button pushed function: PruebadeEntrenamientoButton
        function PruebadeEntrenamientoButtonPushed(app, event)
            GUI_PruebaEntrenamiento
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {585, 585, 585};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {585, 585};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {236, '1x', 222};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RealizarEntrenamientoButton = uibutton(app.LeftPanel, 'push');
            app.RealizarEntrenamientoButton.ButtonPushedFcn = createCallbackFcn(app, @RealizarEntrenamientoButtonPushed, true);
            app.RealizarEntrenamientoButton.Text = 'Realizar Entrenamiento';
            app.AlphaLabel = uilabel(app.LeftPanel);
            app.AlphaLabel.Text = 'Alpha:';
            app.AlphaSpinner = uispinner(app.LeftPanel);
            app.AlphaSpinner.Step = 0.01;
            app.AlphaSpinner.ValueChangedFcn = createCallbackFcn(app, @AlphaSpinnerValueChanged, true);
            app.AlphaSpinner.Value = 0.01;
            app.pocasLabel = uilabel(app.LeftPanel);
            app.pocasLabel.Text = 'Épocas:';
            app.pocasSpinner = uispinner(app.LeftPanel);
            app.pocasSpinner.Step = 50;
            app.pocasSpinner.ValueDisplayFormat = '%.0f';
            app.pocasSpinner.ValueChangedFcn = createCallbackFcn(app, @pocasSpinnerValueChanged, true);
            app.pocasSpinner.Value = 1000;
            app.NeuronasLabel = uilabel(app.LeftPanel);
            app.NeuronasLabel.Text = 'Neuronas:';
            app.NeuronasSpinner = uispinner(app.LeftPanel);
            app.NeuronasSpinner.ValueDisplayFormat = '%.0f';
            app.NeuronasSpinner.ValueChangedFcn = createCallbackFcn(app, @NeuronasSpinnerValueChanged, true);
            app.NeuronasSpinner.Value = 10;
            app.ValoresparaentrenamientoLabel = uilabel(app.LeftPanel);
            app.ValoresparaentrenamientoLabel.Text = 'Valores para entrenamiento';
            app.CenterPanel = uipanel(app.GridLayout);
            app.EntrenamientoRedNeuronalMulticapaLabel = uilabel(app.CenterPanel);
            app.EntrenamientoRedNeuronalMulticapaLabel.Text = 'Entrenamiento Red Neuronal Multicapa';
            app.PruebadeEntrenamientoButton = uibutton(app.CenterPanel, 'push');
            app.PruebadeEntrenamientoButton.ButtonPushedFcn = createCallbackFcn(app, @PruebadeEntrenamientoButtonPushed, true);
            app.PruebadeEntrenamientoButton.Text = 'Prueba de Entrenamiento';
            app.TerminasteelentrenamientoRealizaunapruebaLabel = uilabel(app.CenterPanel);
            app.TerminasteelentrenamientoRealizaunapruebaLabel.Text = '¿Terminaste el entrenamiento? Realiza una prueba.';
            app.eTextAreaLabel = uilabel(app.CenterPanel);
            app.eTextAreaLabel.Text = 'e';
            app.eTextArea = uitextarea(app.CenterPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.TTextAreaLabel = uilabel(app.RightPanel);
            app.TTextAreaLabel.Text = 'T';
            app.TTextArea = uitextarea(app.RightPanel);
            app.ATextAreaLabel = uilabel(app.RightPanel);
            app.ATextAreaLabel.Text = 'A';
            app.ATextArea = uitextarea(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Entrenamiento

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end