classdef GUI_PruebaEntrenamiento < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        LetraEntrenamientoTextArea    matlab.ui.control.TextArea
        LetraEntrenamientoTextAreaLabel  matlab.ui.control.Label
        CenterPanel                   matlab.ui.container.Panel
        ReconocimientodeLetrasLabel   matlab.ui.control.Label
        AnalizarButton                matlab.ui.control.Button
        TipodeletraDropDown           matlab.ui.control.DropDown
        TipodeletraDropDownLabel      matlab.ui.control.Label
        EscogerletraDropDown          matlab.ui.control.DropDown
        EscogerletraDropDownLabel     matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        PruebaconDistorsinButton      matlab.ui.control.Button
        LetraReconocidaTextArea       matlab.ui.control.TextArea
        LetraReconocidaTextAreaLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        letra = 'A'; % A es la letra por default
        letraInt;
        tipo = '1'; % 1 es el tipo de letra por default
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AnalizarButton
        function AnalizarButtonPushed(app, event)
            app.LetraEntrenamientoTextArea.Value = '';
            app.LetraReconocidaTextArea.Value = '';

            if(app.letra == 'A')
                letraInt = 1;
            end
            if(app.letra == 'B')
                letraInt = 2;
            end
            if(app.letra == 'C')
                letraInt = 3;
            end
            if(app.letra == 'D')
                letraInt = 4;
            end
            if(app.letra == 'E')
                letraInt = 5;
            end
            if(app.letra == 'J')
                letraInt = 6;
            end
            if(app.letra == 'K')
                letraInt = 7;
            end

            if(app.tipo == '1')
                letraEspecifica = letraInt;
            end
            if(app.tipo == '2')
                letraEspecifica = letraInt + 7;
            end
            if(app.tipo == '3')
                letraEspecifica = letraInt + 14;
            end

            [entrada, salida] = PruebaEntrenamiento(letraEspecifica);
            app.LetraEntrenamientoTextArea.Value = string(entrada);
            app.LetraReconocidaTextArea.Value = string(salida);
        end

        % Value changed function: EscogerletraDropDown
        function EscogerletraDropDownValueChanged(app, event)
           app.letra = app.EscogerletraDropDown.Value;
        end

        % Value changed function: TipodeletraDropDown
        function TipodeletraDropDownValueChanged(app, event)
           app.tipo = app.TipodeletraDropDown.Value;
        end

        % Button pushed function: PruebaconDistorsinButton
        function PruebaconDistorsinButtonPushed(app, event)
            GUI_PruebaDistorsion;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LetraEntrenamientoTextAreaLabel = uilabel(app.LeftPanel);
            app.LetraEntrenamientoTextAreaLabel.Text = 'Letra Entrenamiento';
            app.LetraEntrenamientoTextArea = uitextarea(app.LeftPanel);
            app.CenterPanel = uipanel(app.GridLayout);
            app.EscogerletraDropDownLabel = uilabel(app.CenterPanel);
            app.EscogerletraDropDownLabel.Text = 'Escoger letra';
            app.EscogerletraDropDown = uidropdown(app.CenterPanel);
            app.EscogerletraDropDown.Items = {'A', 'B', 'C', 'D', 'E', 'J', 'K'};
            app.EscogerletraDropDown.ValueChangedFcn = createCallbackFcn(app, @EscogerletraDropDownValueChanged, true);
            app.EscogerletraDropDown.Value = 'A';
            app.TipodeletraDropDownLabel = uilabel(app.CenterPanel);
            app.TipodeletraDropDownLabel.Text = 'Tipo de letra';
            app.TipodeletraDropDown = uidropdown(app.CenterPanel);
            app.TipodeletraDropDown.Items = {'1', '2', '3'};
            app.TipodeletraDropDown.ValueChangedFcn = createCallbackFcn(app, @TipodeletraDropDownValueChanged, true);
            app.TipodeletraDropDown.Value = '1';
            app.AnalizarButton = uibutton(app.CenterPanel, 'push');
            app.AnalizarButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizarButtonPushed, true);
            app.AnalizarButton.Text = 'Analizar';
            app.ReconocimientodeLetrasLabel = uilabel(app.CenterPanel);
            app.ReconocimientodeLetrasLabel.Text = 'Reconocimiento de Letras';
            app.RightPanel = uipanel(app.GridLayout);
            app.LetraReconocidaTextAreaLabel = uilabel(app.RightPanel);
            app.LetraReconocidaTextAreaLabel.Text = 'Letra Reconocida';
            app.LetraReconocidaTextArea = uitextarea(app.RightPanel);
            app.PruebaconDistorsinButton = uibutton(app.RightPanel, 'push');
            app.PruebaconDistorsinButton.ButtonPushedFcn = createCallbackFcn(app, @PruebaconDistorsinButtonPushed, true);
            app.PruebaconDistorsinButton.Text = 'Prueba con Distorsión';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_PruebaEntrenamiento

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end